classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputAudioFilewavfileLabel  matlab.ui.control.Label
        SelectwavFileButton         matlab.ui.control.Button
        FileselectedLabel           matlab.ui.control.Label
        FileselectedEditField       matlab.ui.control.EditField
        EncryptButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        file % Description
        path
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        drawnow;
            app.UIFigure.WindowState = 'normal';

        end

        % Callback function
        function RecordAudioButtonPushed(app, event)
            
        end

        % Callback function: FileselectedEditField, 
        % SelectwavFileButton
        function SelectwavFileButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            drawnow;
            figure(app.UIFigure);
            app.FileselectedEditField.Value = fullfile(app.path,app.file);
            y = Original_Waveform(app.path,app.file);
            plot(app.UIAxes,y);
            app.UIAxes.Title.String = 'Original Waveform';
            app.UIAxes.Title.FontSize = 16;
            app.UIAxes.XLabel.String = 'Samples';
            app.UIAxes.XLabel.FontSize = 14;
            app.UIAxes.YLabel.String = 'Amplitude';
            app.UIAxes.YLabel.FontSize = 14;
            
        end

        % Callback function
        function FileselectedEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            %file = app.SelectwavFileButton
            %path = get(handles.SelectwavFileButtonPushed,'path');
                ReadWAV(app.path,app.file);
                msgbox('Encrypted Successfully!!','Success','help');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputAudioFilewavfileLabel = uilabel(app.UIFigure);
            app.InputAudioFilewavfileLabel.Text = 'Input: Audio File (*.wav file)';
            app.SelectwavFileButton = uibutton(app.UIFigure, 'push');
            app.SelectwavFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectwavFileButtonPushed, true);
            app.SelectwavFileButton.Text = 'Select *wav File';
            app.FileselectedLabel = uilabel(app.UIFigure);
            app.FileselectedLabel.Text = 'File selected :';
            app.FileselectedEditField = uieditfield(app.UIFigure, 'text');
            app.FileselectedEditField.ValueChangedFcn = createCallbackFcn(app, @SelectwavFileButtonPushed, true);
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end